classdef TissueSegmentation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        CropandSaveTab                 matlab.ui.container.Tab
        ThresholdSlider_disp           matlab.ui.control.Slider
        ThresholdSlider_2Label         matlab.ui.control.Label
        ContourOverlayButton           matlab.ui.control.StateButton
        TransparancySlider             matlab.ui.control.Slider
        TransparancySliderLabel        matlab.ui.control.Label
        MaskOverlayButton              matlab.ui.control.StateButton
        RegionOverlayButton            matlab.ui.control.StateButton
        OverlaySelectedSliceDropDown   matlab.ui.control.DropDown
        RegionSelectionPanel           matlab.ui.container.Panel
        SliceEditField                 matlab.ui.control.NumericEditField
        SliceEditFieldLabel            matlab.ui.control.Label
        FlippedButton                  matlab.ui.control.StateButton
        AzimuthEditField               matlab.ui.control.NumericEditField
        AzimuthEditFieldLabel          matlab.ui.control.Label
        CropandSaveButton              matlab.ui.control.Button
        RangeEditField                 matlab.ui.control.NumericEditField
        RangeEditFieldLabel            matlab.ui.control.Label
        RotationEditField              matlab.ui.control.NumericEditField
        RotationSlider                 matlab.ui.control.Slider
        RotationSliderLabel            matlab.ui.control.Label
        ImageSettingsPanel             matlab.ui.container.Panel
        LoadDataButton                 matlab.ui.control.Button
        NeedleAzEditField              matlab.ui.control.NumericEditField
        NeedleAzEditFieldLabel         matlab.ui.control.Label
        NeedleRaEditField              matlab.ui.control.NumericEditField
        NeedleRaEditFieldLabel         matlab.ui.control.Label
        VoxelSizeEditField             matlab.ui.control.NumericEditField
        VoxelSizeEditFieldLabel        matlab.ui.control.Label
        ElevationLengthEditField       matlab.ui.control.NumericEditField
        ElevationLengthEditFieldLabel  matlab.ui.control.Label
        AzimuthLengthEditField         matlab.ui.control.NumericEditField
        AzimuthLengthEditFieldLabel    matlab.ui.control.Label
        RangeLengthEditField           matlab.ui.control.NumericEditField
        RangeLengthEditFieldLabel      matlab.ui.control.Label
        LockButton                     matlab.ui.control.Button
        SetupPanel                     matlab.ui.container.Panel
        FileLabel_2                    matlab.ui.control.Label
        SelectFolderButton             matlab.ui.control.Button
        FileSelectDropDown             matlab.ui.control.DropDown
        FileSelectDropDownLabel        matlab.ui.control.Label
        TrialNumberEditField           matlab.ui.control.NumericEditField
        TrialNumberEditFieldLabel      matlab.ui.control.Label
        azimuthSlider                  matlab.ui.control.Slider
        rangeSlider                    matlab.ui.control.Slider
        ImageAxis                      matlab.ui.control.UIAxes
        DSegmentationTab               matlab.ui.container.Tab
        ShowMaskButton                 matlab.ui.control.StateButton
        DSegmentationPanel             matlab.ui.container.Panel
        GranularityEditField           matlab.ui.control.NumericEditField
        GranularityEditFieldLabel      matlab.ui.control.Label
        AutoDrawROIButton              matlab.ui.control.Button
        ContrastHighSlider             matlab.ui.control.Slider
        ContrastHighSliderLabel        matlab.ui.control.Label
        ContrastLowSlider              matlab.ui.control.Slider
        ContrastLowSliderLabel         matlab.ui.control.Label
        DrawRectangleButton            matlab.ui.control.Button
        DeleteMaskButton               matlab.ui.control.Button
        SelectedSliceDropDown          matlab.ui.control.DropDown
        SelectedSliceDropDownLabel     matlab.ui.control.Label
        EditandSaveMaskButton          matlab.ui.control.Button
        ThresholdSlider                matlab.ui.control.Slider
        ThresholdSliderLabel           matlab.ui.control.Label
        ImageAxisSeg                   matlab.ui.control.UIAxes
        DVisualizationTab              matlab.ui.container.Tab
        DVisualizationPanel            matlab.ui.container.Panel
        VolumeEditField                matlab.ui.control.NumericEditField
        VolumeEditFieldLabel           matlab.ui.control.Label
        ComputedValuesLabel            matlab.ui.control.Label
        ElevationCentroidEditField     matlab.ui.control.NumericEditField
        ElevationCentroidLabel         matlab.ui.control.Label
        AzimuthCentroidEditField       matlab.ui.control.NumericEditField
        AzimuthCentroidEditFieldLabel  matlab.ui.control.Label
        RangeCentroidEditField         matlab.ui.control.NumericEditField
        RangeCentroidEditFieldLabel    matlab.ui.control.Label
        IntersliceThicknessEditField   matlab.ui.control.NumericEditField
        IntersliceThicknessEditFieldLabel  matlab.ui.control.Label
        SliceWIdthEditField            matlab.ui.control.NumericEditField
        SliceWIdthLabel                matlab.ui.control.Label
        AdjustcenterinAzRaCheckBox     matlab.ui.control.CheckBox
        InterpolationSettingsLabel     matlab.ui.control.Label
        VolumeSettingsLabel            matlab.ui.control.Label
        InterpolationMethodDropDown    matlab.ui.control.DropDown
        InterpolationMethodDropDownLabel  matlab.ui.control.Label
        PreviewButton                  matlab.ui.control.Button
        SmoothingSigmaEditField        matlab.ui.control.NumericEditField
        SmoothingSigmaEditFieldLabel   matlab.ui.control.Label
        NeedleLocationDropDown         matlab.ui.control.DropDown
        NeedleLocationDropDownLabel    matlab.ui.control.Label
        FirstslwneedletrackEditField   matlab.ui.control.NumericEditField
        FirstslwneedletrackEditFieldLabel  matlab.ui.control.Label
        NeedleHeightEditField          matlab.ui.control.NumericEditField
        NeedleHeightEditFieldLabel     matlab.ui.control.Label
        ImagingBottomEditField         matlab.ui.control.NumericEditField
        ImagingBottomEditFieldLabel    matlab.ui.control.Label
        ImagingTopEditField            matlab.ui.control.NumericEditField
        ImagingTopEditFieldLabel       matlab.ui.control.Label
        SaveDataButton                 matlab.ui.control.Button
        CreateMaskButton               matlab.ui.control.Button
        raelPreview                    matlab.ui.control.UIAxes
        azelPreview                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        segClass;
        appState;
        mostRecentSliceInd;
        displayOverlay; 
        getImLambda;
        targetRectPosX;
        targetRectPosY;
        rectPos;
        contImage;
    end
    
    methods (Access = public)
        
        function appStateChange(app,newState)
            %% UI control
            % disable and enable interactivity based on current app state
            switch newState 
                case 0 % init
                    app.clearData();
                    app.ImageSettingsPanelEnable('on');
                    app.FileIOPanelEnable('off');
                    app.RegionPanelEnable('off');
                    app.SegPanelEnable('off');
                    app.VisPanelEnable('off');
                    app.RegionOverlayButton.Value = false;
                    app.ContourOverlayButton.Value = false;
                    app.appState = 0;
                    app.MaskOverlayButton.Value = false;
                    app.ShowMaskButton.Value = false;
                    app.targetRectPosX = [];
                    app.targetRectPosY = [];
                    app.rectPos = [];
                case 1 % image settings selected
                    app.ImageSettingsPanelEnable('off');
                    app.FileIOPanelEnable('on');
                    app.RegionPanelEnable('off');
                    app.SegPanelEnable('off');
                    app.VisPanelEnable('off');
                    app.appState = 1;
                case 2 % file selected
                    app.ImageSettingsPanelEnable('off');
                    app.FileIOPanelEnable('on');
                    app.RegionPanelEnable('on');
                    app.SegPanelEnable('off');
                    app.VisPanelEnable('off');
                    app.appState = 2;
                case 3 % image created
                    app.ImageSettingsPanelEnable('off');
                    app.FileIOPanelEnable('on');
                    app.RegionPanelEnable('on');
                    app.SegPanelEnable('on');
                    app.VisPanelEnable('off');
                    app.appState = 3;
                case 4 % mask created
                    app.ImageSettingsPanelEnable('off');
                    app.FileIOPanelEnable('on');
                    app.RegionPanelEnable('on');
                    app.SegPanelEnable('on');
                    app.VisPanelEnable('on');
                    set(app.RegionOverlayButton,'Enable','on')
                    app.appState = 4;
            end
        end
        %%
        function ImageSettingsPanelEnable(app,enable)
            imagePanelChildren = get(app.ImageSettingsPanel,'Children');
            if strcmp(enable,'on')
                set(imagePanelChildren,'Enable','on');
                set(app.LockButton,'Text','Lock 🔒 ')
                set(app.RegionOverlayButton,'Enable','off')
                set(app.OverlaySelectedSliceDropDown,'Enable','off')
            else
                set(imagePanelChildren,'Enable','off');
                set(app.LockButton,'Text','Unlock 🔓')
                set(app.LockButton,'Enable','on')
            end
        end
        function FileIOPanelEnable(app,enable)
            setupPanelChildren = get(app.SetupPanel,'Children');
            if strcmp(enable,'on')
                set(setupPanelChildren,'Enable','on');
            else
                set(setupPanelChildren,'Enable','off');
            end
        end
        function SegPanelEnable(app,enable)
            segPanelChildren = get(app.DSegmentationPanel,'Children');
            if strcmp(enable,'on')
                set(segPanelChildren,'Enable','on');
            else
                set(segPanelChildren,'Enable','off');
            end
        end
        function RegionPanelEnable(app,enable)
            regionPanelChildren = get(app.RegionSelectionPanel,'Children');
            if strcmp(enable,'on')
                set(regionPanelChildren,'Enable','on');
                set(app.azimuthSlider,'Enable','on')
                set(app.rangeSlider,'Enable','on')
                set(app.MaskOverlayButton,'Enable','on')
                set(app.TransparancySlider,'Enable','on')
                set(app.ThresholdSlider_disp,'Enable','on')
                set(app.ContourOverlayButton,'Enable','on')
            else
                set(regionPanelChildren,'Enable','off');
                set(app.azimuthSlider,'Enable','off')
                set(app.rangeSlider,'Enable','off')
                set(app.MaskOverlayButton,'Enable','off')
                set(app.TransparancySlider,'Enable','off')
                set(app.ThresholdSlider_disp,'Enable','off')
                set(app.ContourOverlayButton,'Enable','off')
                
            end
        end
        function VisPanelEnable(app,enable)
            visPanelChildren = get(app.DVisualizationPanel,'Children');
            if strcmp(enable,'on')
                set(visPanelChildren,'Enable','on');
                set(app.OverlaySelectedSliceDropDown,'Enable','on')
            else
                set(visPanelChildren,'Enable','off');
                set(app.OverlaySelectedSliceDropDown,'Enable','off')
            end
        end
        function clearData(app)
            app.FileLabel_2.Text = 'File Not Selected';
            app.SelectedSliceDropDown.Items = {};
            app.OverlaySelectedSliceDropDown.Items = {};
            app.FileSelectDropDown.Items = {};
            delete(app.segClass)
        end
        
        %%
        function initImage(app)
            app.rangeSlider.Limits = [app.segClass.minRange,app.segClass.maxRange];
            app.azimuthSlider.Limits = [app.segClass.minAzimuth,app.segClass.maxAzimuth];
            app.rangeSlider.Value = app.segClass.maxRange;
            app.azimuthSlider.Value = app.segClass.minAzimuth;
            app.RangeEditField.Value = app.rangeSlider.Value;
            app.AzimuthEditField.Value = app.azimuthSlider.Value;
        end
        function displayImage(app)
            flipRange = @(x) app.segClass.maxRange + app.segClass.minRange- x;
            startVec = ceil([flipRange(app.rangeSlider.Value),app.azimuthSlider.Value]);
            rotation = -ceil(app.RotationSlider.Value);
            [subImDisplay,~,boxCoordsX,boxCoordsY,needlePoint] = app.getImLambda(app.segClass,startVec, rotation,app.FlippedButton.Value);
            
            image(app.ImageAxis,subImDisplay); axis(app.ImageAxis,'equal'); axis(app.ImageAxis,'tight')
            hold(app.ImageAxis,'on')
            if app.MaskOverlayButton.Value
                selectedMaskInd = str2num(app.OverlaySelectedSliceDropDown.Value);
                maskDat = app.segClass.getSlicePadded(selectedMaskInd);
                imagesc(app.ImageAxis,maskDat,'AlphaData',app.TransparancySlider.Value); axis(app.ImageAxis,'equal'); axis(app.ImageAxis,'tight')
            end
            if app.RegionOverlayButton.Value
                selectedMaskInd = str2num(app.OverlaySelectedSliceDropDown.Value);
                [roiX,roiY]= app.segClass.getMaskPoly(selectedMaskInd);
                plot(app.ImageAxis,roiX, roiY,'w','LineWidth',3)
            end
            if app.ContourOverlayButton.Value
                selectedMaskInd = str2num(app.OverlaySelectedSliceDropDown.Value);
                maskDat = app.segClass.getSlicePadded(selectedMaskInd);
                maskDat = imgaussfilt(mean(maskDat,3),8); 
                %imagesc(app.ImageAxis,maskDat,'AlphaData',app.TransparancySlider.Value); axis(app.ImageAxis,'equal'); axis(app.ImageAxis,'tight')
                contour(app.ImageAxis,maskDat,[app.ThresholdSlider_disp.Value,app.ThresholdSlider_disp.Value])
            end
            scatter(app.ImageAxis, boxCoordsY, boxCoordsX,200,'w')
            scatter(app.ImageAxis, needlePoint(2), needlePoint(1),200,'w')
            plot(app.ImageAxis,boxCoordsY, boxCoordsX,'w','LineWidth',3)
            hold(app.ImageAxis,'off')
        end
        function sortedList = makePath(app,datIn,gran)
            % find a closed polygon path for a given set of points
            datIn = datIn(2:gran:end,:);
            dist = @(p1,p2) sqrt((p1(1)-p2(1))^2 + (p1(2)-p2(2))^2);
            sortedList = zeros(size(datIn,1)+1,size(datIn,2));
            sortedList(1,:) = datIn(1,:);
            I = 1; 
            ind = 1; 
            while size(datIn,1)~=1
                targetPoint = datIn(I,:);
                sortedList(ind,:) = targetPoint;
                datIn(I,:) = [];
                [~,I] = min(arrayfun(@(x) dist(targetPoint,datIn(x,:)), 1:size(datIn,1)));
                ind = ind+1;
            end
            sortedList(end-1,:) = datIn(1,:);
            sortedList(end,:) = sortedList(1,:); 
        end
        function displaySegmentationImage(app)
            selectedImInd = str2num(app.SelectedSliceDropDown.Value);
            selectedIm = imadjust(app.segClass.getSlice(selectedImInd),[app.ContrastLowSlider.Value,app.ContrastHighSlider.Value]);
            image(app.ImageAxisSeg,selectedIm); axis(app.ImageAxisSeg,'equal'); axis(app.ImageAxisSeg,'tight')
            hold(app.ImageAxisSeg,'on')
            if ~isempty(app.targetRectPosX)
                plot(app.ImageAxisSeg,app.targetRectPosX,app.targetRectPosY)
                filtIm = imgaussfilt(mean(selectedIm,3),8); 
                xVec = floor(app.rectPos(1)):floor(app.rectPos(1)+app.rectPos(3));
                yVec = floor(app.rectPos(2)):floor(app.rectPos(2)+app.rectPos(4));
                [app.contImage,contIm] = contour(app.ImageAxisSeg,xVec,yVec,filtIm(yVec,xVec),[app.ThresholdSlider.Value,app.ThresholdSlider.Value]);
                contIm.LineWidth = 1; 
                app.contImage
            end
            if app.segClass.maskExists(selectedImInd) && app.ShowMaskButton.Value
                [roiX,roiY]= app.segClass.getMaskPolyUnpadded(selectedImInd);
                plot(app.ImageAxisSeg,roiX, roiY,'w','LineWidth',3)
            end
            hold(app.ImageAxisSeg,'off')
        end
        function updateDropDowns(app)
            vals = app.segClass.sliceInds;
            itemsMask = {};
            items = {};
            for i = vals
                if app.segClass.maskExists(i)
                    itemsMask{end+1} = num2str(i);
                end
                items{end+1} = num2str(i);
            end
            app.OverlaySelectedSliceDropDown.Items = itemsMask;
            if app.segClass.maskExists((app.mostRecentSliceInd))
                app.OverlaySelectedSliceDropDown.Value = num2str(app.mostRecentSliceInd);
            end
            app.SelectedSliceDropDown.Items = items;
            app.SelectedSliceDropDown.Value = num2str(app.mostRecentSliceInd);
        end
        function defImageLambda(app)
            app.getImLambda = app.segClass.getSubImClosure(app.FileSelectDropDown.Value);
        end
        function [polyPos,maskOut] = customWaitPolygon(app,hROI)
            % Listen for mouse clicks on the ROI
            function clickCallback(~,evt)
                if strcmp(evt.SelectionType,'double')
                    uiresume;
                end
            end
            l = addlistener(hROI,'ROIClicked',@clickCallback);
            % Block program execution
            uiwait;
            % Remove listener
            delete(l);
            % Return the current position
            polyPos = hROI.Position;
            maskOut = hROI.createMask();
        end
        function [xPos,yPos,rectPos] = customWait(app,hROI)
            % Listen for mouse clicks on the ROI
            function clickCallback(~,evt)
                if strcmp(evt.SelectionType,'double')
                    uiresume;
                end
            end
            l = addlistener(hROI,'ROIClicked',@clickCallback);
            % Block program execution
            uiwait;
            % Remove listener
            delete(l);
            % Return the current position
            pos = hROI.Position;
            rectPos = pos; 
            xPos = [pos(1), pos(1), pos(1)+pos(3), pos(1)+pos(3),pos(1)];
            yPos = [pos(2), pos(2)+pos(4), pos(2)+pos(4), pos(2),pos(2)];
        end
        function drawRect(app)
            h = drawrectangle(app.ImageAxisSeg);
            [app.targetRectPosX,app.targetRectPosY,app.rectPos] = app.customWait(h);
            delete(h)
            app.displaySegmentationImage()
        end
        function [polyPos, maskOut]=drawPoly(app)
            h = drawpolygon(app.ImageAxisSeg,'FaceAlpha',0);
            [polyPos, maskOut] = app.customWaitPolygon(h);
            delete(h)
            app.displaySegmentationImage()
        end
        function [polyPos, maskOut]=drawPolyAuto(app,startPath)
            h = drawpolygon(app.ImageAxisSeg,'Position',startPath,'FaceAlpha',0);
            [polyPos, maskOut] = app.customWaitPolygon(h);
            delete(h)
            app.displaySegmentationImage()
        end
        function succ=load3DMaskSettings(app)
            try
                app.ImagingTopEditField.Value = app.segClass.endHeight; 
                app.ImagingBottomEditField.Value = app.segClass.startHeight;
                app.NeedleHeightEditField.Value = app.segClass.needleHeight;
                app.FirstslwneedletrackEditField.Value = app.segClass.needleTrackSlice;
                switch app.segClass.needleRelativeLoc
                    case 's'
                        app.NeedleLocationDropDown.Value = 'surface';
                    case 'm'
                        app.NeedleLocationDropDown.Value  = 'middle';
                    case 'b'
                        app.NeedleLocationDropDown.Value  = 'bottom';
                end
                succ = true;
            catch
                succ = false;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.appStateChange(0);
            app.displayOverlay = false;
        end

        % Button pushed function: LockButton
        function LockButtonPushed(app, event)
            if app.appState == 0
                app.segClass = TissueSegmentationClass(app.RangeLengthEditField.Value,app.AzimuthLengthEditField.Value,app.ElevationLengthEditField.Value,app.VoxelSizeEditField.Value,[app.NeedleRaEditField.Value, app.NeedleAzEditField.Value]);
                app.appStateChange(1);
            else
                app.appStateChange(0);
            end
        end

        % Callback function
        function SelectFolderButtonPushed(app, event)

        end

        % Callback function
        function SelectFileButtonPushed(app, event)
            succ = app.segClass.uiSetImage();
            if succ == true
                app.FileLabel.Text = app.segClass.targetFile;
                app.appStateChange(2)
                app.initImage();
                app.displayImage();
            end
        end

        % Value changed function: azimuthSlider
        function azimuthSliderValueChanged(app, event)
            value = app.azimuthSlider.Value;
            app.AzimuthEditField.Value = value; 
            app.displayImage()
        end

        % Value changed function: rangeSlider
        function rangeSliderValueChanged(app, event)
            value = app.rangeSlider.Value;
            app.RangeEditField.Value = value; 
            app.displayImage()
        end

        % Value changed function: AzimuthEditField
        function AzimuthEditFieldValueChanged(app, event)
            value = app.AzimuthEditField.Value;
            app.azimuthSlider.Value = value; 
            app.displayImage()
        end

        % Value changed function: RangeEditField
        function RangeEditFieldValueChanged(app, event)
            value = app.RangeEditField.Value;
            app.rangeSlider.Value = value; 
            app.displayImage()
        end

        % Value changing function: RotationSlider
        function RotationSliderValueChanging(app, event)
            changingValue = event.Value;
            app.RotationEditField.Value = changingValue;
            app.displayImage()
        end

        % Button pushed function: CropandSaveButton
        function CropandSaveButtonPushed(app, event)
            flipRange = @(x) app.segClass.maxRange + app.segClass.minRange- x;
            startVec = ceil([flipRange(app.rangeSlider.Value),app.azimuthSlider.Value]);
            rotation = -ceil(app.RotationSlider.Value);
            %[~,outIm,~,~,~] =  app.segClass.getSubIm(startVec, rotation,app.FlippedButton.Value);
            [paddedIm,outIm,~,~,~] = app.getImLambda(app.segClass,startVec, rotation,app.FlippedButton.Value);
            if ~app.segClass.sliceExists(app.SliceEditField.Value)
                app.segClass.addSlice(app.SliceEditField.Value,outIm,paddedIm,app.FlippedButton.Value);
            else
                app.segClass.modifySlice(app.SliceEditField.Value,outIm,paddedIm,app.FlippedButton.Value);
            end
            app.mostRecentSliceInd = app.SliceEditField.Value;
            app.updateDropDowns();
            app.displaySegmentationImage()
            if app.appState == 2
                app.appStateChange(3);
            end
        end

        % Button pushed function: EditandSaveMaskButton
        function EditandSaveMaskButtonPushed(app, event)
            [polyPos, maskOut] = app.drawPoly();
            
            selectedImInd = str2num(app.SelectedSliceDropDown.Value);
            app.segClass.createMaskFromPoly(selectedImInd, polyPos, maskOut);
            %app.segClass.createMask(selectedImInd);
            app.mostRecentSliceInd = str2num(app.SelectedSliceDropDown.Value);
            app.updateDropDowns();
            app.displaySegmentationImage()
            app.displayImage()
            app.appStateChange(4);
        end

        % Value changed function: SelectedSliceDropDown
        function SelectedSliceDropDownValueChanged(app, event)

            app.displaySegmentationImage()
        end

        % Callback function
        function OverlayMaskButtonPushed(app, event)

        end

        % Value changed function: OverlaySelectedSliceDropDown
        function OverlaySelectedSliceDropDownValueChanged(app, event)
            app.displayImage()
        end

        % Value changed function: FlippedButton
        function FlippedButtonValueChanged(app, event)
            app.displayImage()
        end

        % Button pushed function: CreateMaskButton
        function CreateMaskButtonPushed(app, event)

            endHeight = app.ImagingTopEditField.Value;
            startHeight= app.ImagingBottomEditField.Value;
            needleTrackSlice = app.FirstslwneedletrackEditField.Value;
            needleRelativeLoc = app.NeedleLocationDropDown.Value;
            needleHeight = app.NeedleHeightEditField.Value;
            sigma = app.SmoothingSigmaEditField.Value;
            app.segClass.set3DParams(startHeight,endHeight, needleTrackSlice,needleRelativeLoc,needleHeight,sigma);
            interpMethod = app.InterpolationMethodDropDown.Value;
            adjCenter = app.AdjustcenterinAzRaCheckBox.Value;
            mask3d = app.segClass.create3DVolumeOld(interpMethod,adjCenter);
            [raCent,azCent,elCent,vol]=app.segClass.getVolumeStats();
            app.AzimuthCentroidEditField.Value = azCent; 
            app.RangeCentroidEditField.Value = raCent; 
            app.ElevationCentroidEditField.Value = elCent; 
            app.VolumeEditField.Value = vol;
            save(strcat('outputMask3d_Trial',num2str(app.TrialNumberEditField.Value),'.mat'),'mask3d')
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            maskObj = app.segClass; 
            save(strcat('outputMaskObj_Trial',num2str(app.TrialNumberEditField.Value),'.mat'),'maskObj');
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            dat = load(uigetfile());
            app.segClass = dat.maskObj;
            app.mostRecentSliceInd = (app.segClass.sliceInds(1));
            app.FileSelectDropDown.Items = app.segClass.imageFileNameList;    
            app.defImageLambda()
            app.initImage();
            app.displayImage()
            app.updateDropDowns();
            app.load3DMaskSettings();
            app.segClass.updateInitQuantities();
            app.appStateChange(4)
        end

        % Value changed function: RegionOverlayButton
        function RegionOverlayButtonValueChanged(app, event)
            app.displayImage()
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed2(app, event)
            succ = app.segClass.guiSetFolder();
            if succ
                app.FileSelectDropDown.Items = app.segClass.imageFileNameList;    
                app.FileLabel_2.Text = app.segClass.targetFolder;
                app.defImageLambda()
                app.appStateChange(2)
                app.initImage();
                app.displayImage()
            end
        end

        % Value changed function: FileSelectDropDown
        function FileSelectDropDownValueChanged(app, event)
            value = app.FileSelectDropDown.Value;
            app.defImageLambda()
            app.displayImage()
        end

        % Button pushed function: DeleteMaskButton
        function DeleteMaskButtonPushed(app, event)
            selectedImInd = str2num(app.SelectedSliceDropDown.Value);
            app.segClass.deleteMask(selectedImInd);
            app.mostRecentSliceInd = str2num(app.SelectedSliceDropDown.Items{1});
            app.updateDropDowns();
            app.displaySegmentationImage()
            app.displayImage()
            app.appStateChange(4);
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            endHeight = app.ImagingTopEditField.Value;
            startHeight= app.ImagingBottomEditField.Value;
            needleTrackSlice = app.FirstslwneedletrackEditField.Value;
            needleRelativeLoc = app.NeedleLocationDropDown.Value;
            needleHeight = app.NeedleHeightEditField.Value;
            sigma = app.SmoothingSigmaEditField.Value;
            app.segClass.set3DParams(startHeight,endHeight, needleTrackSlice,needleRelativeLoc,needleHeight,sigma);
            %app.segClass.create3DVolumeOld();
            app.segClass.needleLocAZRA = [app.NeedleRaEditField.Value,app.NeedleAzEditField.Value];
            [prvMatAz,prvMatRa, nl, raVec,azVec, elVec]=app.segClass.geometryPreview();
            imagesc(app.azelPreview,azVec,elVec, prvMatAz)
            colormap(app.azelPreview,gray)
            set(app.azelPreview,'YDir','normal')
            axis(app.azelPreview,'equal'); axis(app.azelPreview,'tight')
            hold(app.azelPreview, 'on')
            scatter(app.azelPreview,app.NeedleAzEditField.Value,needleHeight,200,'red','filled')
            hold(app.azelPreview, 'off')
            imagesc(app.raelPreview,raVec,elVec, prvMatRa)
            colormap(app.raelPreview,gray)
            set(app.raelPreview,'YDir','normal')
            axis(app.raelPreview,'equal'); axis(app.raelPreview,'tight')
            hold(app.raelPreview, 'on')
            scatter(app.raelPreview,app.NeedleRaEditField.Value,needleHeight,200,'red','filled')
            hold(app.raelPreview, 'off')
            
            app.SliceWIdthEditField.Value = app.segClass.dEl;
        end

        % Value changed function: TransparancySlider
        function TransparancySliderValueChanged(app, event)
            value = app.TransparancySlider.Value;
            app.displayImage()
        end

        % Value changed function: MaskOverlayButton
        function MaskOverlayButtonValueChanged(app, event)
            value = app.MaskOverlayButton.Value;
            app.displayImage()
        end

        % Button pushed function: DrawRectangleButton
        function DrawRectangleButtonPushed(app, event)
            app.drawRect();
            a = 1;
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            value = app.ThresholdSlider.Value;
            app.displaySegmentationImage()
        end

        % Value changed function: ShowMaskButton
        function ShowMaskButtonValueChanged(app, event)
            value = app.ShowMaskButton.Value;
            app.displaySegmentationImage()
        end

        % Value changed function: ContrastLowSlider
        function ContrastLowSliderValueChanged(app, event)
            value = app.ContrastLowSlider.Value;
            if value > app.ContrastHighSlider.Value
                app.ContrastLowSlider.Value = app.ContrastHighSlider.Value;
            end
            app.displaySegmentationImage()
        end

        % Value changed function: ContrastHighSlider
        function ContrastHighSliderValueChanged(app, event)
            value = app.ContrastHighSlider.Value;
            if value < app.ContrastLowSlider.Value
                app.ContrastHighSlider.Value = app.ContrastLowSlider.Value;
            end
            app.displaySegmentationImage()
        end

        % Value changed function: ContourOverlayButton
        function ContourOverlayButtonValueChanged(app, event)
            app.displayImage();
        end

        % Value changed function: ThresholdSlider_disp
        function ThresholdSlider_dispValueChanged(app, event)
            app.displayImage();
        end

        % Button pushed function: AutoDrawROIButton
        function AutoDrawROIButtonPushed(app, event)
            numVerts = [];
            numVerts(1) = app.contImage(2,1);
            currInd = 1; 
            while currInd+numVerts(end)+1 < size(app.contImage,2)
                currInd = currInd + numVerts(end)+1;
                numVerts(end+1) = app.contImage(2,currInd);
            end
            [largestPath,largestPathInd] = max(numVerts);
            dataStart = sum(numVerts(1:largestPathInd-1))+ (largestPathInd-1)*2+1;
            vertInds = dataStart+1:app.GranularityEditField.Value:(dataStart+largestPath-1);
            pathStart = app.contImage(:,vertInds);
            [polyPos, maskOut] = app.drawPolyAuto(pathStart');
            selectedImInd = str2num(app.SelectedSliceDropDown.Value);
            app.segClass.createMaskFromPoly(selectedImInd, polyPos, maskOut);
            %app.segClass.createMask(selectedImInd);
            app.mostRecentSliceInd = str2num(app.SelectedSliceDropDown.Value);
            app.updateDropDowns();
            app.displaySegmentationImage()
            app.displayImage()
            app.appStateChange(4);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CropandSaveTab = uitab(app.TabGroup);
            app.CropandSaveTab.Title = 'Crop and Save';
            app.ImageAxis = uiaxes(app.CropandSaveTab);
            app.ImageAxis.XLim = [0 1];
            app.ImageAxis.XTick = [];
            app.ImageAxis.YTick = [];
            app.rangeSlider = uislider(app.CropandSaveTab);
            app.rangeSlider.MajorTicks = [];
            app.rangeSlider.ValueChangedFcn = createCallbackFcn(app, @rangeSliderValueChanged, true);
            app.rangeSlider.MinorTicks = [];
            app.azimuthSlider = uislider(app.CropandSaveTab);
            app.azimuthSlider.MajorTicks = [];
            app.azimuthSlider.ValueChangedFcn = createCallbackFcn(app, @azimuthSliderValueChanged, true);
            app.azimuthSlider.MinorTicks = [];
            app.SetupPanel = uipanel(app.CropandSaveTab);
            app.SetupPanel.Title = 'Setup';
            app.TrialNumberEditFieldLabel = uilabel(app.SetupPanel);
            app.TrialNumberEditFieldLabel.Text = 'Trial Number';
            app.TrialNumberEditField = uieditfield(app.SetupPanel, 'numeric');
            app.FileSelectDropDownLabel = uilabel(app.SetupPanel);
            app.FileSelectDropDownLabel.Text = 'File Select';
            app.FileSelectDropDown = uidropdown(app.SetupPanel);
            app.FileSelectDropDown.Items = {};
            app.FileSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @FileSelectDropDownValueChanged, true);
            app.FileSelectDropDown.Value = {};
            app.SelectFolderButton = uibutton(app.SetupPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed2, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.FileLabel_2 = uilabel(app.SetupPanel);
            app.FileLabel_2.Text = 'Folder Not Selected';
            app.ImageSettingsPanel = uipanel(app.CropandSaveTab);
            app.ImageSettingsPanel.Title = 'Image Settings';
            app.LockButton = uibutton(app.ImageSettingsPanel, 'push');
            app.LockButton.ButtonPushedFcn = createCallbackFcn(app, @LockButtonPushed, true);
            app.LockButton.Text = 'Lock 🔒';
            app.RangeLengthEditFieldLabel = uilabel(app.ImageSettingsPanel);
            app.RangeLengthEditFieldLabel.Text = 'Range Length:';
            app.RangeLengthEditField = uieditfield(app.ImageSettingsPanel, 'numeric');
            app.RangeLengthEditField.Value = 82;
            app.AzimuthLengthEditFieldLabel = uilabel(app.ImageSettingsPanel);
            app.AzimuthLengthEditFieldLabel.Text = 'Azimuth Length:';
            app.AzimuthLengthEditField = uieditfield(app.ImageSettingsPanel, 'numeric');
            app.AzimuthLengthEditField.Value = 82;
            app.ElevationLengthEditFieldLabel = uilabel(app.ImageSettingsPanel);
            app.ElevationLengthEditFieldLabel.Text = 'Elevation Length:';
            app.ElevationLengthEditField = uieditfield(app.ImageSettingsPanel, 'numeric');
            app.ElevationLengthEditField.Value = 100;
            app.VoxelSizeEditFieldLabel = uilabel(app.ImageSettingsPanel);
            app.VoxelSizeEditFieldLabel.Text = 'Voxel Size';
            app.VoxelSizeEditField = uieditfield(app.ImageSettingsPanel, 'numeric');
            app.VoxelSizeEditField.Value = 1;
            app.NeedleRaEditFieldLabel = uilabel(app.ImageSettingsPanel);
            app.NeedleRaEditFieldLabel.Text = 'Needle Ra';
            app.NeedleRaEditField = uieditfield(app.ImageSettingsPanel, 'numeric');
            app.NeedleRaEditField.Value = 52;
            app.NeedleAzEditFieldLabel = uilabel(app.ImageSettingsPanel);
            app.NeedleAzEditFieldLabel.Text = 'Needle Az';
            app.NeedleAzEditField = uieditfield(app.ImageSettingsPanel, 'numeric');
            app.NeedleAzEditField.Value = 41;
            app.LoadDataButton = uibutton(app.ImageSettingsPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.RegionSelectionPanel = uipanel(app.CropandSaveTab);
            app.RegionSelectionPanel.Title = 'Region Selection';
            app.RotationSliderLabel = uilabel(app.RegionSelectionPanel);
            app.RotationSliderLabel.Text = 'Rotation';
            app.RotationSlider = uislider(app.RegionSelectionPanel);
            app.RotationSlider.Limits = [-30 30];
            app.RotationSlider.ValueChangingFcn = createCallbackFcn(app, @RotationSliderValueChanging, true);
            app.RotationEditField = uieditfield(app.RegionSelectionPanel, 'numeric');
            app.RangeEditFieldLabel = uilabel(app.RegionSelectionPanel);
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField = uieditfield(app.RegionSelectionPanel, 'numeric');
            app.RangeEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
            app.CropandSaveButton = uibutton(app.RegionSelectionPanel, 'push');
            app.CropandSaveButton.ButtonPushedFcn = createCallbackFcn(app, @CropandSaveButtonPushed, true);
            app.CropandSaveButton.Text = 'Crop and Save';
            app.AzimuthEditFieldLabel = uilabel(app.RegionSelectionPanel);
            app.AzimuthEditFieldLabel.Text = 'Azimuth';
            app.AzimuthEditField = uieditfield(app.RegionSelectionPanel, 'numeric');
            app.AzimuthEditField.ValueChangedFcn = createCallbackFcn(app, @AzimuthEditFieldValueChanged, true);
            app.FlippedButton = uibutton(app.RegionSelectionPanel, 'state');
            app.FlippedButton.ValueChangedFcn = createCallbackFcn(app, @FlippedButtonValueChanged, true);
            app.FlippedButton.Text = 'Flipped?';
            app.SliceEditFieldLabel = uilabel(app.RegionSelectionPanel);
            app.SliceEditFieldLabel.Text = 'Slice';
            app.SliceEditField = uieditfield(app.RegionSelectionPanel, 'numeric');
            app.OverlaySelectedSliceDropDown = uidropdown(app.CropandSaveTab);
            app.OverlaySelectedSliceDropDown.Items = {};
            app.OverlaySelectedSliceDropDown.ValueChangedFcn = createCallbackFcn(app, @OverlaySelectedSliceDropDownValueChanged, true);
            app.OverlaySelectedSliceDropDown.Value = {};
            app.RegionOverlayButton = uibutton(app.CropandSaveTab, 'state');
            app.RegionOverlayButton.ValueChangedFcn = createCallbackFcn(app, @RegionOverlayButtonValueChanged, true);
            app.RegionOverlayButton.Text = 'Region Overlay';
            app.MaskOverlayButton = uibutton(app.CropandSaveTab, 'state');
            app.MaskOverlayButton.ValueChangedFcn = createCallbackFcn(app, @MaskOverlayButtonValueChanged, true);
            app.MaskOverlayButton.Text = 'Mask Overlay';
            app.TransparancySliderLabel = uilabel(app.CropandSaveTab);
            app.TransparancySliderLabel.Text = 'Transparancy';
            app.TransparancySlider = uislider(app.CropandSaveTab);
            app.TransparancySlider.Limits = [0 1];
            app.TransparancySlider.ValueChangedFcn = createCallbackFcn(app, @TransparancySliderValueChanged, true);
            app.ContourOverlayButton = uibutton(app.CropandSaveTab, 'state');
            app.ContourOverlayButton.ValueChangedFcn = createCallbackFcn(app, @ContourOverlayButtonValueChanged, true);
            app.ContourOverlayButton.Text = 'Contour Overlay';
            app.ThresholdSlider_2Label = uilabel(app.CropandSaveTab);
            app.ThresholdSlider_2Label.Text = 'Threshold';
            app.ThresholdSlider_disp = uislider(app.CropandSaveTab);
            app.ThresholdSlider_disp.Limits = [1 150];
            app.ThresholdSlider_disp.ValueChangedFcn = createCallbackFcn(app, @ThresholdSlider_dispValueChanged, true);
            app.ThresholdSlider_disp.Value = 1;
            app.DSegmentationTab = uitab(app.TabGroup);
            app.DSegmentationTab.Title = '2D Segmentation';
            app.ImageAxisSeg = uiaxes(app.DSegmentationTab);
            app.ImageAxisSeg.XLim = [0 1];
            app.ImageAxisSeg.XTick = [];
            app.ImageAxisSeg.YTick = [];
            app.DSegmentationPanel = uipanel(app.DSegmentationTab);
            app.DSegmentationPanel.Title = '2D Segmentation';
            app.ThresholdSliderLabel = uilabel(app.DSegmentationPanel);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.DSegmentationPanel);
            app.ThresholdSlider.Limits = [1 150];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.Value = 1;
            app.EditandSaveMaskButton = uibutton(app.DSegmentationPanel, 'push');
            app.EditandSaveMaskButton.ButtonPushedFcn = createCallbackFcn(app, @EditandSaveMaskButtonPushed, true);
            app.EditandSaveMaskButton.Text = 'Edit and Save Mask';
            app.SelectedSliceDropDownLabel = uilabel(app.DSegmentationPanel);
            app.SelectedSliceDropDownLabel.Text = 'Selected Slice';
            app.SelectedSliceDropDown = uidropdown(app.DSegmentationPanel);
            app.SelectedSliceDropDown.Items = {};
            app.SelectedSliceDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedSliceDropDownValueChanged, true);
            app.SelectedSliceDropDown.Value = {};
            app.DeleteMaskButton = uibutton(app.DSegmentationPanel, 'push');
            app.DeleteMaskButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteMaskButtonPushed, true);
            app.DeleteMaskButton.Text = 'Delete Mask';
            app.DrawRectangleButton = uibutton(app.DSegmentationPanel, 'push');
            app.DrawRectangleButton.ButtonPushedFcn = createCallbackFcn(app, @DrawRectangleButtonPushed, true);
            app.DrawRectangleButton.Text = 'Draw Rectangle';
            app.ContrastLowSliderLabel = uilabel(app.DSegmentationPanel);
            app.ContrastLowSliderLabel.Text = 'Contrast Low';
            app.ContrastLowSlider = uislider(app.DSegmentationPanel);
            app.ContrastLowSlider.Limits = [0 1];
            app.ContrastLowSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastLowSliderValueChanged, true);
            app.ContrastHighSliderLabel = uilabel(app.DSegmentationPanel);
            app.ContrastHighSliderLabel.Text = 'Contrast High';
            app.ContrastHighSlider = uislider(app.DSegmentationPanel);
            app.ContrastHighSlider.Limits = [0 1];
            app.ContrastHighSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastHighSliderValueChanged, true);
            app.ContrastHighSlider.Value = 1;
            app.AutoDrawROIButton = uibutton(app.DSegmentationPanel, 'push');
            app.AutoDrawROIButton.ButtonPushedFcn = createCallbackFcn(app, @AutoDrawROIButtonPushed, true);
            app.AutoDrawROIButton.Text = 'Auto Draw ROI';
            app.GranularityEditFieldLabel = uilabel(app.DSegmentationPanel);
            app.GranularityEditFieldLabel.Text = 'Granularity';
            app.GranularityEditField = uieditfield(app.DSegmentationPanel, 'numeric');
            app.GranularityEditField.Value = 20;
            app.ShowMaskButton = uibutton(app.DSegmentationTab, 'state');
            app.ShowMaskButton.ValueChangedFcn = createCallbackFcn(app, @ShowMaskButtonValueChanged, true);
            app.ShowMaskButton.Text = 'Show Mask';
            app.DVisualizationTab = uitab(app.TabGroup);
            app.DVisualizationTab.Title = '3D Visualization';
            app.azelPreview = uiaxes(app.DVisualizationTab);
            title(app.azelPreview, 'Elevation Azimuth Geometry Preview')
            xlabel(app.azelPreview, 'Azimuth')
            ylabel(app.azelPreview, 'Elevation')
            app.raelPreview = uiaxes(app.DVisualizationTab);
            title(app.raelPreview, 'Elevation Range Geometry Preview')
            xlabel(app.raelPreview, 'Range')
            ylabel(app.raelPreview, 'Elevation')
            app.DVisualizationPanel = uipanel(app.DVisualizationTab);
            app.DVisualizationPanel.Title = '3D Visualization Panel';
            app.CreateMaskButton = uibutton(app.DVisualizationPanel, 'push');
            app.CreateMaskButton.ButtonPushedFcn = createCallbackFcn(app, @CreateMaskButtonPushed, true);
            app.CreateMaskButton.Text = 'Create Mask';
            app.SaveDataButton = uibutton(app.DVisualizationPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.ImagingTopEditFieldLabel = uilabel(app.DVisualizationPanel);
            app.ImagingTopEditFieldLabel.Text = 'Imaging Top';
            app.ImagingTopEditField = uieditfield(app.DVisualizationPanel, 'numeric');
            app.ImagingBottomEditFieldLabel = uilabel(app.DVisualizationPanel);
            app.ImagingBottomEditFieldLabel.Text = 'Imaging Bottom';
            app.ImagingBottomEditField = uieditfield(app.DVisualizationPanel, 'numeric');
            app.NeedleHeightEditFieldLabel = uilabel(app.DVisualizationPanel);
            app.NeedleHeightEditFieldLabel.Text = 'Needle Height';
            app.NeedleHeightEditField = uieditfield(app.DVisualizationPanel, 'numeric');
            app.FirstslwneedletrackEditFieldLabel = uilabel(app.DVisualizationPanel);
            app.FirstslwneedletrackEditFieldLabel.Text = 'First sl w/needle track';
            app.FirstslwneedletrackEditField = uieditfield(app.DVisualizationPanel, 'numeric');
            app.NeedleLocationDropDownLabel = uilabel(app.DVisualizationPanel);
            app.NeedleLocationDropDownLabel.Text = 'Needle Location';
            app.NeedleLocationDropDown = uidropdown(app.DVisualizationPanel);
            app.NeedleLocationDropDown.Items = {'surface', 'middle', 'bottom'};
            app.NeedleLocationDropDown.Value = 'surface';
            app.SmoothingSigmaEditFieldLabel = uilabel(app.DVisualizationPanel);
            app.SmoothingSigmaEditFieldLabel.Text = 'Smoothing Sigma';
            app.SmoothingSigmaEditField = uieditfield(app.DVisualizationPanel, 'numeric');
            app.PreviewButton = uibutton(app.DVisualizationPanel, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.InterpolationMethodDropDownLabel = uilabel(app.DVisualizationPanel);
            app.InterpolationMethodDropDownLabel.Text = 'Interpolation Method';
            app.InterpolationMethodDropDown = uidropdown(app.DVisualizationPanel);
            app.InterpolationMethodDropDown.Items = {'nearest', 'linear', 'cubic', 'makima', 'spline', 'pchip'};
            app.InterpolationMethodDropDown.Value = 'nearest';
            app.VolumeSettingsLabel = uilabel(app.DVisualizationPanel);
            app.VolumeSettingsLabel.Text = 'Volume Settings';
            app.InterpolationSettingsLabel = uilabel(app.DVisualizationPanel);
            app.InterpolationSettingsLabel.Text = 'Interpolation Settings';
            app.AdjustcenterinAzRaCheckBox = uicheckbox(app.DVisualizationPanel);
            app.AdjustcenterinAzRaCheckBox.Text = 'Adjust center in Az/Ra';
            app.SliceWIdthLabel = uilabel(app.DVisualizationPanel);
            app.SliceWIdthLabel.Text = {'Slice '; 'WIdth'};
            app.SliceWIdthEditField = uieditfield(app.DVisualizationPanel, 'numeric');
            app.SliceWIdthEditField.Editable = 'off';
            app.IntersliceThicknessEditFieldLabel = uilabel(app.DVisualizationPanel);
            app.IntersliceThicknessEditFieldLabel.Text = 'Interslice Thickness';
            app.IntersliceThicknessEditField = uieditfield(app.DVisualizationPanel, 'numeric');
            app.RangeCentroidEditFieldLabel = uilabel(app.DVisualizationPanel);
            app.RangeCentroidEditFieldLabel.Text = {'Range '; 'Centroid'; ''};
            app.RangeCentroidEditField = uieditfield(app.DVisualizationPanel, 'numeric');
            app.RangeCentroidEditField.Editable = 'off';
            app.AzimuthCentroidEditFieldLabel = uilabel(app.DVisualizationPanel);
            app.AzimuthCentroidEditFieldLabel.Text = {'Azimuth '; 'Centroid'; ''};
            app.AzimuthCentroidEditField = uieditfield(app.DVisualizationPanel, 'numeric');
            app.AzimuthCentroidEditField.Editable = 'off';
            app.ElevationCentroidLabel = uilabel(app.DVisualizationPanel);
            app.ElevationCentroidLabel.Text = {'Elevation '; 'Centroid'};
            app.ElevationCentroidEditField = uieditfield(app.DVisualizationPanel, 'numeric');
            app.ElevationCentroidEditField.Editable = 'off';
            app.ComputedValuesLabel = uilabel(app.DVisualizationPanel);
            app.ComputedValuesLabel.Text = 'Computed Values';
            app.VolumeEditFieldLabel = uilabel(app.DVisualizationPanel);
            app.VolumeEditFieldLabel.Text = 'Volume';
            app.VolumeEditField = uieditfield(app.DVisualizationPanel, 'numeric');
            app.VolumeEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TissueSegmentation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end